classdef vfmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InputVideoFileButton      matlab.ui.control.Button
        VelocityEditFieldLabel    matlab.ui.control.Label
        VelocityEditField         matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        DensitySliderLabel        matlab.ui.control.Label
        DensitySlider             matlab.ui.control.Slider
        VectorsButton             matlab.ui.control.StateButton
        StreamlinesButton         matlab.ui.control.StateButton
        LengthSlider              matlab.ui.control.Slider
        LengthSliderLabel         matlab.ui.control.Label
        ColourSliderLabel         matlab.ui.control.Label
        ColourSlider              matlab.ui.control.Slider
        ChooseFrameSliderLabel    matlab.ui.control.Label
        ChooseFrameSlider         matlab.ui.control.Slider
        ColourVectorsButton       matlab.ui.control.StateButton
        BoundaryButton            matlab.ui.control.Button
        CentreButton              matlab.ui.control.Button
        CropColorBarButton        matlab.ui.control.Button
        SlowButton                matlab.ui.control.Button
        FastButton                matlab.ui.control.Button
        playpauseButton           matlab.ui.control.StateButton
        VectorsVideoButton        matlab.ui.control.StateButton
        StreamlinesVideoButton    matlab.ui.control.StateButton
        ComputeButton             matlab.ui.control.StateButton
        colourvectorsvideoButton  matlab.ui.control.StateButton
    end


    properties (Access = public)
        file = 'exampleVideo.avi'; % Description
        curFrame = 0;
        BoundaryPoints = 0;
        color_bar = 0;
        preImage = 0;
        roiPoints = 0;
        U = 0;
        centrex = 0;
        centrey = 0;
        v3 = 0;
        Velocityvalue = 0;
        avgVu = 0;
        avgVv = 0;
        Xj = 0;
        Yj = 0;
        q = 0;
        VrXY = 0;
        theeeta = 0;
        avgVaziXY = 0;
        X = 0;
        Y = 0;
        r = 1;
        g = 1;
        b = 0;
        hand = 0;
        q1 = 0;
        q2 = 0;
        q3 = 0;
        q4 = 0;
        q5 = 0;
        q6 = 0;
        q7 = 0;
        q8 = 0;
        q9 = 0;
        q10 = 0;
        q11 = 0;
        q12 = 0;
        q13 = 0;
        q14 = 0;
        q15 = 0;
        q16 = 0;
        q17 = 0;
        q18 = 0;
        q19 = 0;
        q20 = 0;
        q21 = 0;
        q22 = 0;
        q23 = 0;
        q24 = 0;
        speed = 0;
        frm = 1;
        qvid = 0;
        handvid = 0;
        slow = 1;
        fast = 1;
        
    end

    properties (Access = private)
        Property50 % Description
    end

    methods (Access = public)


        function Computation(app,roiPoints,~,U)
            %% Storing Vr(r,t) from U(x,y)

            tmin = min(roiPoints(:,7));
            tmax = max(roiPoints(:,8));
            trange = round(10*(tmax - tmin))+1;
            VrPolar = zeros(length(roiPoints(:,1)),trange);


            %imshow(Image);
            for r = 1 : length(roiPoints(:,1))
                for tc = 1 : 1 : trange
                     tp = tmin + (tc - 1)*(tmax - tmin)/(trange - 1);
                    if( tp >= roiPoints(r,7) && tp <= roiPoints(r,8)) 
                     
                     xv = round(roiPoints(r,3)* cosd(tp) + app.centrex); 
                     yv = round(roiPoints(r,3)* sind(-tp) + app.centrey);
                
                     VrPolar(r,tc) = U(yv,xv);  
                     
                     
            %              if(mod(r,5) == 0)
            %                 axis on;
            %                 hold on;
            %                 plot(xv,yv, 'y.', 'MarkerSize', 5, 'LineWidth', 2);
            %              end
                    end
                end    
            end
            disp("Stored VrPolar");

            %% Using Median Filter in the radial direction  on VrPolar
            %VrPolarFilt = VrPolar; 
            
            VrPolarFilt = medfilt1(VrPolar,3); %radially implies along a constant theta
            VrPolar = VrPolarFilt;
            disp("Calculated filtered values of VrPolar" );

            %% Calculating VaziNPolar and VaziPPolar

            VaziNPolar = zeros(size(VrPolar));
            VaziPPolar = zeros(size(VrPolar));
            [~,gVr] = gradient(VrPolar); % w.r.t r (i.e. difference b/w rows)
             Integrand =zeros(size(VrPolar));
            for r = 1:1: length(roiPoints(:,1))
             
                    Integrand(r,:) = -roiPoints(r,3).*gVr(r,:) - VrPolar(r,:);
                    cols = find(VrPolar(r,:) ~= 0 );
                    if(isempty(cols))
                      ci = 1;
                      cf = 1;
                    else
                        ci = cols(1);
                        cf = cols(end);
                    end    
                    VaziNPolar(r,ci:cf) = cumtrapz(Integrand(r,ci:cf)); % along theta i.e., along rows. varies with columns
                                    %include the required number of columns you want in Integrand
                
            end
             disp("Calculated VaziNPolar"); % Some values are too huge
             
             % Calculating VaziPPolar
             
             IntegrandFlip = flip(Integrand,2);
             VrPolarFlip = flip(VrPolar,2);
             
            for r = 1:1: length(roiPoints(:,1))
             
                    IntegrandFlip(r,:) = -IntegrandFlip(r,:);
                    cols = find(VrPolarFlip(r,:) ~= 0 );
                    if(isempty(cols)==0)
                        ci = cols(1);
                        cf = cols(end);
                        VaziPPolar(r,ci:cf) = cumtrapz(IntegrandFlip(r,ci:cf)); % along theta i.e., along rows. varies with columns
                                    %include the required number of columns you want in Integrand
                    end
            end
            disp("Calculated VaziPPolar");
%%
% %% Using Median Filter in the radial direction on VaziPPolar VaziNPolar-------------Miscellaneous filter
% %VrPolarFilt = VrPolar; 
% VaziPPolarFilt = medfilt1(VaziPPolar,3); %radially implies along a constant theta
% VaziPPolar = VaziPPolarFilt ;
% VaziNPolarFilt = medfilt1(VaziNPolar,3); %radially implies along a constant theta
% VaziNPolar = VaziNPolarFilt ;
% 
%  disp("Calculated filtered values of VaziPPolar and VaziNPolar (radial median filter)" );

%%  Back to Cartesian co-ordinates (VrXY and VaziXY)
            VrXY = zeros(size(U));
            VaziXY = zeros(size(U));
            VaziNXY = zeros(size(U));
            VaziPXY = zeros(size(U));
            for r = 1:1:length(roiPoints(:,1))
                for tc = 1 : 1 : trange
                     tp = tmin + (tc - 1)*(tmax - tmin)/(trange - 1);
                    if( tp >= roiPoints(r,7) && tp <= roiPoints(r,8)) 
                     xv = round(roiPoints(r,3)* cosd(tp) + app.centrex); 
                     yv = round(roiPoints(r,3)* sind(-tp) + app.centrey);
                     VrXY(yv,xv) = VrPolar(r,tc);
                     VaziNXY(yv,xv) = VaziNPolar(r,tc);
                     VaziPXY(yv,xv) = VaziPPolar(r,tc);
            %              if(mod(r,5) == 0)
            %                 axis on;
            %                 hold on;
            %                 plot(xv,yv, 'y.', 'MarkerSize', 5, 'LineWidth', 2);
            %              end
                    end
                end    
            end
              %disp(VrROIxy);
              app.VrXY = VrXY;
              disp("obtained Vrxy,VaziNxy,VaziPxy");
  

              %% Calculating weight matrix
            
              W = zeros(size(U));
             
             for r = 1:1:length(VrXY(:,1))
                 
                  Nz   = find(VrXY(r,:) ~= 0);
                  
                  if(length(Nz)> 1)
                      FnzCol = Nz(1);
                      LnzCol = Nz(end);
                      for c = FnzCol:1:LnzCol
                          W(r,c) = (c-FnzCol)/(LnzCol - FnzCol);  
                      end
                  end
                
             end
              disp("Calculated weight matrix");
              
               %% Calculating Vazixy
                  
                VaziXY = W.*VaziNXY + (1-W).*VaziPXY;
                disp("Calculated VaziXY from weight matrix");
                  

              %% Averaging the VaziXY
              
              avgFilter = [1,1,1;1,2,1;1,1,1];
             %disp(size(avgFilter));
             %disp(size(VaziXY));
              %averagedVazi = conv2(VaziXY,avgFilter,'same');%include 0.1 for the averaging filter
              
              averagedVazi = medfilt2(VaziXY); %Used medfilt2 instead of conv2
              app.avgVaziXY = averagedVazi.*0.001;%---------------------------------------------------problem was here
              
              disp("Calculated Average(Fake) VaziXY by using the filter");
              
              %% Plotting Velocity vectors in/perp. to the direction of beam
            %Finding the angle
            [row,col] = size(U);
            app.theeeta = zeros(size(U));
            for i = 1:row
                for j = 1:col
                vpoint = [j - app.centrex, i - app.centrey];
                angle = acosd(dot(app.v3, vpoint) / (norm(app.v3) * norm(vpoint)) );
                app.theeeta(i,j) = angle;
                end
            end
            disp("Calculated the angle for the vectors to be inclined towards the beam");
            %removed the function call of next function


        end


        function ComputationForvecstream(app)
                        
            [Nx, Ny,~] = size(app.preImage);
            xidx = 1:1:Nx;
            yidx = 1:1:Ny;
            [app.X,app.Y] = meshgrid(xidx,yidx);
            
            % Finding the 2 components of the 2 vectors
            %% Decrease the number of vectors to be plotted
            if(app.DensitySlider.Value < 5)
                jump = 5;
            else 
                jump=round(app.DensitySlider.Value/5); % get this value from the slider
            end
            app.Yj = app.Y(1:jump:end,1:jump:end);
            app.Xj = app.X(1:jump:end,1:jump:end);
            VrXYj = app.VrXY(1:jump:end,1:jump:end);
            theeetaj=app.theeeta(1:jump:end,1:jump:end);
            avgVaziXYj = app.avgVaziXY(1:jump:end,1:jump:end);
            disp("initialised the jump values");
            %% Plotting VrXY in the beam direction
            UVrj = -VrXYj.*cosd(theeetaj);
            VVrj = VrXYj.*sind(theeetaj);
            
            
            %% Plotting Vazixy perpendicular to the beam direction
            %avgVaziXY = VaziXY;
            %avgVaziXYj = avgVaziXY(1:jump:end,1:jump:end);
            UVazij = -avgVaziXYj.*cosd(90 - theeetaj);
            VVazij = -avgVaziXYj.*sind(90 - theeetaj);
            
            
            %% Plotting the final vector V
            
            Vuj = UVrj + UVazij;
            Vvj = VVrj + VVazij;
            
            disp(" Sum of perpendicular components and sum of parallel components of V");
            %% Averaging the final vectors
  
              avgFilter = [1,1,1;1,2,1;1,1,1];
            
              %averagedVu = conv2(Vuj,avgFilter,'same');
             % avgVu = averagedVu.*1;
              
              app.avgVu = medfilt2(Vuj); %% using median filter instead of conv2
              
              %averagedVv = conv2(Vvj,avgFilter,'same');
             % avgVv = averagedVv.*1;
              app.avgVv = medfilt2(Vvj);
              disp("Calculated Average Vu and Vv");
           
        end
        
        function PlotVectors(app)

        if(app.LengthSlider.Value == 0)
         sc = 0.05;
        else 
          sc =  app.LengthSlider.Value/20;
        end  
        %q = quiver(Yj',Xj',UVr,VVr,2);%to see Vr plot
        %q = quiver(Yj',Xj',UVazi,VVazi,2);%to see Vazi plot
% %         q = quiver(Yj',Xj',avgVu*sc,avgVv*sc,'AutoScale','off');%to see V plot
% %        
% %         %%q = quiver(avgVu*sc,avgVv*sc,'AutoScale','off');
% %         q.Color = 'yellow';
% %         q.MaxHeadSize = 1.5;
% %         q.MarkerSize = 10;
% %         disp("plotted few V vectors w.r.t the beam direction");   
        
         ax = app.UIAxes;
         
        hold(ax,'on');
        app.q = quiver(ax,app.Yj',app.Xj',app.avgVu*sc,app.avgVv*sc,'AutoScale','off');
        app.q.Color = [app.r app.g app.b];
        app.q.MaxHeadSize = 1.5;
        app.q.MarkerSize = 10;
        disp("plotted few V vectors w.r.t the beam direction"); 
        end
        
        function PlotVectors2(app)

            if(app.LengthSlider.Value == 0)
             sc = 0.05;
            else 
              sc =  app.LengthSlider.Value/20;
            end  
            %q = quiver(Yj',Xj',UVr,VVr,2);%to see Vr plot
            %q = quiver(Yj',Xj',UVazi,VVazi,2);%to see Vazi plot
    % %         q = quiver(Yj',Xj',avgVu*sc,avgVv*sc,'AutoScale','off');%to see V plot
    % %        
    % %         %%q = quiver(avgVu*sc,avgVv*sc,'AutoScale','off');
    % %         q.Color = 'yellow';
    % %         q.MaxHeadSize = 1.5;
    % %         q.MarkerSize = 10;
    % %         disp("plotted few V vectors w.r.t the beam direction");   
            
             ax = app.UIAxes2;
             
            hold(ax,'on');
            app.qvid = quiver(ax,app.Yj',app.Xj',app.avgVu*sc,app.avgVv*sc,'AutoScale','off');
            app.qvid.Color = [app.r app.g app.b];
            app.qvid.MaxHeadSize = 1.5;
            app.qvid.MarkerSize = 10;
            disp("plotted few V vectors w.r.t the beam direction in the video"); 
        end
        
        
        function CalculateUxy(app,color_bar,Velocityvalue,preImage)
            
            if nargin < 2
               color_bar = app.color_bar;
               Velocityvalue = app.Velocityvalue;
               preImage = app.preImage;
            end
            
            cmap = double(color_bar); 
            mindata = -Velocityvalue; %set the value from the image
            maxdata = Velocityvalue; 
            disp(Velocityvalue);
            % Map the velocities and calculate U(x,y)

            [rows,cols,~] = size(preImage);
            app.U = zeros(rows,cols);
            for locY = 1:rows
                for locX = 1:cols
  
                    rvalue = double(preImage(locY,locX,1));
                    gvalue = double(preImage(locY,locX,2));
                    bvalue = double(preImage(locY,locX,3));
                    rgbInBar = [rvalue,gvalue,bvalue]; 

                    repeat = repmat(rgbInBar,length(cmap),1);
                    compare = cmap - repeat ;
                    compare = compare.^2;
                    comparetotal = sum(compare,2);
                    [~,rowLoc] = min(sqrt(comparetotal));  %the minimumn euclidean
                                                                    % distance
            
                    currentPointVelocity = mindata+(maxdata-mindata)*rowLoc/length(cmap);%U(x,y)
                    app.U(locY,locX) = currentPointVelocity;
                   
                end
            end
             %disp(size(app.U));
            disp("calculated U(x,y)");
            %result = app.U;
  
         end
    end

    methods (Access = public)
    
        function Preprocess(app)
            Image=getimage(app.UIAxes);
            medImage = medfilt3(Image);
            disp("Median filtering the rgb Image");
            gaussImage = imgaussfilt(medImage,4); % SD = 8 pixels
            disp("Gaussian Smoothing the image");
            app.preImage = gaussImage;
            disp("the image is preprocessed");
            
        end
        function Preprocess2(app)
            Image=getimage(app.UIAxes2);
            medImage = medfilt3(Image);
            disp("Median filtering the rgb Image");
            gaussImage = imgaussfilt(medImage,4); % SD = 8 pixels
            disp("Gaussian Smoothing the image");
            app.preImage = gaussImage;
            disp("the image is preprocessed");
            
        end
        
        function PlotStreamlines(app)
            ax = app.UIAxes;
      
            hold(ax,'on');
            app.hand = streamslice(ax,app.Yj',app.Xj',app.avgVu,app.avgVv,15);
            set( app.hand, 'Color', [app.r app.g app.b] );
            disp("Plotted the streamlines by including same number of points");
            
        end
        
        function PlotStreamlines2(app)
            ax = app.UIAxes2;
      
            hold(ax,'on');
            app.handvid = streamslice(ax,app.Yj',app.Xj',app.avgVu,app.avgVv,15);
            set( app.handvid, 'Color', [app.r app.g app.b] );
            disp("Plotted the streamlines by including same number of points");
            
        end
        
    end

    methods (Access = public)
    
        function manyColourvectorsvideo(app)
            
                Vangle = atan2(app.avgVv,app.avgVu).*180/pi;
                if(app.LengthSlider.Value == 0)
                     sc = 0.05;
                else 
                     sc =  app.LengthSlider.Value/20;
                end 
                if(app.DensitySlider.Value < 5)
                    jump = 5;
                else 
                    jump=round(app.DensitySlider.Value/5); % get this value from the slider
                end
                ax = app.UIAxes2;
                disp("Going to display 24 vectors");
         
                hold(ax,'on');
                n = 1000; %% ---------------------set the least sufficient size
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 75  && Vangle(i,j) < 90)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
               hold(ax,'on');
                if( r ~= 0)
                    
                app.q1 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q1.Color = [199 197 23].*0.0039;
                app.q1.MaxHeadSize = 1.5;
                app.q1.MarkerSize = 10;
                end
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 60  && Vangle(i,j) < 75)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                
                hold(ax,'on');
                if( r ~= 0)
                    
                app.q2 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q2.Color = [231 204 3].*0.0039;
                app.q2.MaxHeadSize = 1.5;
                app.q2.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 45  && Vangle(i,j) < 60)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q3 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q3.Color = [229 189 16].*0.0039;
                app.q3.MaxHeadSize = 1.5;
                app.q3.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 30  && Vangle(i,j) < 45)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q4 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q4.Color = [230 160 13].*0.0039;
                app.q4.MaxHeadSize = 1.5;
                app.q4.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 15  && Vangle(i,j) < 30)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q5 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q5.Color = [226 139 1].*0.0039;
                app.q5.MaxHeadSize = 1.5;
                app.q5.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)> 0  && Vangle(i,j) < 15)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q6 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q6.Color = [222 98 0].*0.0039;
                app.q6.MaxHeadSize = 1.5;
                app.q6.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -15  && Vangle(i,j) < 0)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q7 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q7.Color = [204 67 23].*0.0039;
                app.q7.MaxHeadSize = 1.5;
                app.q7.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -30  && Vangle(i,j) < -15)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q8 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q8.Color = [163 6 29].*0.0039;
                app.q8.MaxHeadSize = 1.5;
                app.q8.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -45  && Vangle(i,j) < -30)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q9 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q9.Color = [144 3 50].*0.0039;
                app.q9.MaxHeadSize = 1.5;
                app.q9.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -60  && Vangle(i,j) < -45)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q10 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q10.Color = [120 0 61].*0.0039;
                app.q10.MaxHeadSize = 1.5;
                app.q10.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -75  && Vangle(i,j) < -60)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q11 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q11.Color = [90 8 74].*0.0039;
                app.q11.MaxHeadSize = 1.5;
                app.q11.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -90  && Vangle(i,j) < -75)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q12 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q12.Color = [64 12 87].*0.0039;
                app.q12.MaxHeadSize = 1.5;
                app.q12.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -105  && Vangle(i,j) < -90)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q13 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q13.Color = [28 15 84].*0.0039;
                app.q13.MaxHeadSize = 1.5;
                app.q13.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -120  && Vangle(i,j) < -105)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q14 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q14.Color = [28 38 102].*0.0039;
                app.q14.MaxHeadSize = 1.5;
                app.q14.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -135  && Vangle(i,j) < -120)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q15 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q15.Color = [20 51 106].*0.0039;
                app.q15.MaxHeadSize = 1.5;
                app.q15.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -150  && Vangle(i,j) < -135)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on'); 
                if( r ~= 0)
                app.q16 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q16.Color = [0 71 105].*0.0039;
                app.q16.MaxHeadSize = 1.5;
                app.q16.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -165  && Vangle(i,j) < -150)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q17 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q17.Color = [2 80 108].*0.0039;
                app.q17.MaxHeadSize = 1.5;
                app.q17.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -180  && Vangle(i,j) < -165)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q18 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q18.Color = [0 93 89].*0.0039;
                app.q18.MaxHeadSize = 1.5;
                app.q18.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 165  && Vangle(i,j) < 180 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q19 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q19.Color = [2 104 103].*0.0039;
                app.q19.MaxHeadSize = 1.5;
                app.q19.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 150  && Vangle(i,j) < 165 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q20 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q20.Color = [4 108 84].*0.0039;
                app.q20.MaxHeadSize = 1.5;
                app.q20.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 135  && Vangle(i,j) < 150 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q21 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q21.Color = [10 115 75].*0.0039;
                app.q21.MaxHeadSize = 1.5;
                app.q21.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 120  && Vangle(i,j) < 135 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q22 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q22.Color = [6 124 62].*0.0039;
                app.q22.MaxHeadSize = 1.5;
                app.q22.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 105  && Vangle(i,j) < 120 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q23 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q23.Color = [82 155 31].*0.0039;
                app.q23.MaxHeadSize = 1.5;
                app.q23.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if( Vangle(i,j)>= 90  && Vangle(i,j) < 105 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q24 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off');
                app.q24.Color = [148 173 19].*0.0039;
                app.q24.MaxHeadSize = 1.5;
                app.q24.MarkerSize = 10;
                end
                
                disp("plotted vectors with 24 different colours");
            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseFrameSlider
        function ChooseFrame(app, event)
            value = app.ChooseFrameSlider.Value;
            videoObject = VideoReader(app.file);
            if(app.VectorsButton.Value == 1)
                delete(app.q);
            end
            if(app.StreamlinesButton.Value == 1)
                delete(app.hand);
            end
            if(app.ColourVectorsButton.Value == 1)
                quiverObj = [app.q1,app.q2,app.q3,app.q4,app.q5,app.q6,app.q7,app.q8,app.q9,app.q10,app.q11,app.q12,app.q13,app.q14,app.q15,app.q16,app.q17,app.q18,app.q19,app.q20,app.q21,app.q22,app.q23,app.q24];
                delete(quiverObj);
            end
            
            ax1 = app.UIAxes;
            vidFrame = read(videoObject);
            nFrames = videoObject.NumberOfFrames;
            f = 1 + round( (nFrames - 1)*(value - 1)/99);
            image(vidFrame(:,:,:,f), 'Parent',ax1);
            set(ax1, 'Visible', 'off');
             
            
        end

        % Value changed function: colourvectorsvideoButton
        function ColourVectorsVideo(app, event)
            value = app.colourvectorsvideoButton.Value;
            if(app.colourvectorsvideoButton.Value == 0)
              quiverObj = [app.q1,app.q2,app.q3,app.q4,app.q5,app.q6,app.q7,app.q8,app.q9,app.q10,app.q11,app.q12,app.q13,app.q14,app.q15,app.q16,app.q17,app.q18,app.q19,app.q20,app.q21,app.q22,app.q23,app.q24];
              delete(quiverObj);
              
            end
        end

        % Value changed function: ComputeButton
        function ComputingFunction(app, event)
            value = app.ComputeButton.Value;
            Preprocess(app);
            CalculateUxy(app);
            Computation(app,app.roiPoints,app.preImage,app.U);
            ComputationForvecstream(app);
            
        end

        % Button pushed function: BoundaryButton
        function DrawBoundary(app, event)
            Image=getimage(app.UIAxes);  
            imshow(Image);
            h = imfreehand(); 
            pause;
            position = getPosition(h); 
            disp("choose the middle x");
            [xmid,~] = ginput(1);
            Xmid = xmid(1);
            removed = round(sortrows(position,[2 1]));
            app.BoundaryPoints = zeros(removed(end,2)- removed(1,2)+1,4);
            for i = removed(1,2):1:removed(end,2)
                n = i - removed(1,2) + 1;
                rowVal = find(removed(:,2)==i);
                if(isempty(rowVal)||length(rowVal)==1)
       
                    app.BoundaryPoints(n,:) = [app.BoundaryPoints(n-1,1),i,app.BoundaryPoints(n-1,3),i];
        
                else 
                     iniX = rowVal(1);
                     iniF = rowVal(end);
                     if((removed(iniX,1) > Xmid || removed(iniF,1) < Xmid) && n > 1)
                        app.BoundaryPoints(n,:) = [app.BoundaryPoints(n-1,1),i,app.BoundaryPoints(n-1,3),i];
            
                     else
                        app.BoundaryPoints(n,:) = [removed(iniX,1),i,removed(iniF,1),i];
                     end
                end
            end 
            axis on;
            hold on;
            plot(app.BoundaryPoints(:,3),app.BoundaryPoints(:,4), 'r.', 'MarkerSize', 30, 'LineWidth', 2);
            axis on;
            hold on;
            plot(app.BoundaryPoints(:,1),app.BoundaryPoints(:,2), 'r.', 'MarkerSize', 30, 'LineWidth', 2);
            disp("Obtained boundary");
           
        end

        % Button pushed function: FastButton
        function Fastvideo(app, event)
            app.fast = app.fast + 1;
        end

        % Button pushed function: CentreButton
        function GetCentre(app, event)
            Image=getimage(app.UIAxes);  
           
            img = imshow(Image);
            img.AlphaData = 0.5;
            [x,y] = ginput(4);
            hold on;
            m1 = ( y(2) - y(1) )/( x(2) - x(1) ); % slopes of the two lines
            m2 = ( y(4) - y(3))/( x(4) - x(3) );
            
            x(6) = ( y(4) - y(2) + m1*x(2) - m2*x(4) )/(m1 - m2); % x beam centre
            y(6) = m1*( x(6) - x(2) ) + y(2); % y beam centre
            app.centrex = x(6);
            app.centrey = y(6);
            x(7) = x(6)-80; %line from the beam centre parallel to x axis
            y(7) = y(6);
            
            line(x(1:2), y(1:2)); %drawing the 3 lines
            line(x(3:4), y(3:4));
            line(x(6:7), y(6:7));
            pause;
            hold off;
            
            %% Get the radius at each co-ordinate
            
            app.roiPoints = zeros(length(app.BoundaryPoints(:,1)),6);
            app.roiPoints(:,1:6) = [app.BoundaryPoints(:,1),app.BoundaryPoints(:,2),zeros(size(app.BoundaryPoints(:,1))),app.BoundaryPoints(:,3),app.BoundaryPoints(:,4),zeros(size(app.BoundaryPoints(:,1)))];
            for n = 1:length(app.roiPoints(:,1))
                app.roiPoints(n,3) = sqrt( ( x(6) - app.roiPoints(n,1) )^2 + ( y(6) - app.roiPoints(n,2) )^2);
                app.roiPoints(n,6) = sqrt( ( x(6) - app.roiPoints(n,4) )^2 + ( y(6) - app.roiPoints(n,5) )^2);
            end
            disp("radii are stored");
            %%
            Dummy = zeros(size(app.roiPoints(:,1:3)));
            rowpos = 0;
            
            for i = 1 : length(app.roiPoints(:,1))
               rows = find(round(app.roiPoints(:,6))==round(app.roiPoints(i,3)));
                if(isempty(rows))
                   rows = find(round(app.roiPoints(:,6))==round(app.roiPoints(i,3))+1);
                end
                if(isempty(rows))
                  rows = find(round(app.roiPoints(:,6))==round(app.roiPoints(i,3))-1);
                end
                if(rowpos==0)
                   rowpos = i; 
                end
                if(isempty(rows)==0)
        
                    rowpos = rows(1);
                    Dummy(i,:) = [app.roiPoints(rowpos,4),app.roiPoints(rowpos,5),app.roiPoints(rowpos,6)];
                else    
                     Dummy(i,:) = [app.roiPoints(rowpos,4),app.roiPoints(rowpos,5),app.roiPoints(rowpos,6)];
                end
               
            end
            app.roiPoints(:,4:6) = Dummy;
            disp("Dummy Matrix (x2,y2,r2) is obtained");
            %% 
            % Calculating the initial and final angles for each radius
            app.v3 = [x(7) - x(6), y(7) - y(6)]; % vector for the parallel line
            for i = 1 : length(app.roiPoints(:,1))
                v4 = [app.roiPoints(i,1) - x(6), app.roiPoints(i,2) - y(6)];
                v5 = [app.roiPoints(i,4) - x(6), app.roiPoints(i,5) - y(6)];
                app.roiPoints(i,7) = acosd(dot(app.v3, v4) / (norm(app.v3) * norm(v4)) ) + 180 ;
                app.roiPoints(i,8) = acosd(dot(v5, app.v3) / (norm(app.v3) * norm(v5)) ) + 180 ;
            end
            disp("angles are stored after getting centre");
        end

        % Button pushed function: CropColorBarButton
        function GetColorbar(app, event)
            % Preprocess the image
            Image=getimage(app.UIAxes);
            medImage = medfilt3(Image);
            disp("Median filtering the rgb Image");
            gaussImage = imgaussfilt(medImage,4); % SD = 8 pixels
            disp("Gaussian Smoothing the image");
            imwrite(gaussImage,'frame_pre.tif');
            disp("the image is preprocessed and stored");
            %imwrite(gaussImage,'colorImage_10pre.JPG');
            %imshowpair(gaussImage,medImage,'montage');
            
            % Get the colorBar
            
            disp("crop the color bar");
            app.preImage = imread('frame_pre.tif'); 
            imshow(app.preImage);
            hrec = imrect;
            pause;
            
            position = getPosition(hrec); 
            croppedColorBar = imcrop(app.preImage,position);
            hold off;
            %figure; 
            %imshow(croppedColorBar);    
            app.color_bar = squeeze(uint8((mean(croppedColorBar,2))));% color_bar contains a single rgb colour
            %disp(color_bar);
            disp("Cropped the color_bar");
            %------got the color bar as 2D matrix with r g b as columns----------------
            
        end

        % Value changed function: VelocityEditField
        function GetVelocityColorbar(app, event)
            Velocityvalue = app.VelocityEditField.Value;
            app.Velocityvalue = Velocityvalue;
            disp("Obtained the velocity value");
            %app.U = CalculateUxy(app,app.color_bar,Velocityvalue,app.preImage);
            
        end

        % Value changed function: ColourVectorsButton
        function ManyColourVectors(app, event)
            value = app.ColourVectorsButton.Value;
            if( value == 1)
                Vangle = atan2(app.avgVv,app.avgVu).*180/pi;
                if(app.LengthSlider.Value == 0)
                     sc = 0.05;
                else 
                     sc =  app.LengthSlider.Value/20;
                end 
                if(app.DensitySlider.Value < 5)
                    jump = 5;
                else 
                    jump=round(app.DensitySlider.Value/5); % get this value from the slider
                end
                ax = app.UIAxes;
                disp("Going to display 24 vectors");
         
                hold(ax,'on');
                n = 1000; %% ---------------------set the least sufficient size
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 75  && Vangle(i,j) < 90)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
               hold(ax,'on');
                if( r ~= 0)
                    
                app.q1 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q1.Color = [199 197 23].*0.0039;
                app.q1.MaxHeadSize = 1.5;
                app.q1.MarkerSize = 10;
                end
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 60  && Vangle(i,j) < 75)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                
                hold(ax,'on');
                if( r ~= 0)
                    
                app.q2 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q2.Color = [231 204 3].*0.0039;
                app.q2.MaxHeadSize = 1.5;
                app.q2.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 45  && Vangle(i,j) < 60)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q3 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q3.Color = [229 189 16].*0.0039;
                app.q3.MaxHeadSize = 1.5;
                app.q3.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 30  && Vangle(i,j) < 45)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q4 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q4.Color = [230 160 13].*0.0039;
                app.q4.MaxHeadSize = 1.5;
                app.q4.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 15  && Vangle(i,j) < 30)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q5 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q5.Color = [226 139 1].*0.0039;
                app.q5.MaxHeadSize = 1.5;
                app.q5.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)> 0  && Vangle(i,j) < 15)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q6 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q6.Color = [222 98 0].*0.0039;
                app.q6.MaxHeadSize = 1.5;
                app.q6.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -15  && Vangle(i,j) < 0)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q7 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q7.Color = [204 67 23].*0.0039;
                app.q7.MaxHeadSize = 1.5;
                app.q7.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -30  && Vangle(i,j) < -15)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q8 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q8.Color = [163 6 29].*0.0039;
                app.q8.MaxHeadSize = 1.5;
                app.q8.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -45  && Vangle(i,j) < -30)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q9 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q9.Color = [144 3 50].*0.0039;
                app.q9.MaxHeadSize = 1.5;
                app.q9.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -60  && Vangle(i,j) < -45)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q10 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q10.Color = [120 0 61].*0.0039;
                app.q10.MaxHeadSize = 1.5;
                app.q10.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -75  && Vangle(i,j) < -60)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q11 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q11.Color = [90 8 74].*0.0039;
                app.q11.MaxHeadSize = 1.5;
                app.q11.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -90  && Vangle(i,j) < -75)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q12 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q12.Color = [64 12 87].*0.0039;
                app.q12.MaxHeadSize = 1.5;
                app.q12.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -105  && Vangle(i,j) < -90)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q13 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q13.Color = [28 15 84].*0.0039;
                app.q13.MaxHeadSize = 1.5;
                app.q13.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -120  && Vangle(i,j) < -105)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q14 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q14.Color = [28 38 102].*0.0039;
                app.q14.MaxHeadSize = 1.5;
                app.q14.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -135  && Vangle(i,j) < -120)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q15 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q15.Color = [20 51 106].*0.0039;
                app.q15.MaxHeadSize = 1.5;
                app.q15.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -150  && Vangle(i,j) < -135)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on'); 
                if( r ~= 0)
                app.q16 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q16.Color = [0 71 105].*0.0039;
                app.q16.MaxHeadSize = 1.5;
                app.q16.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -165  && Vangle(i,j) < -150)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q17 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q17.Color = [2 80 108].*0.0039;
                app.q17.MaxHeadSize = 1.5;
                app.q17.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= -180  && Vangle(i,j) < -165)
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q18 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q18.Color = [0 93 89].*0.0039;
                app.q18.MaxHeadSize = 1.5;
                app.q18.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 165  && Vangle(i,j) < 180 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q19 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q19.Color = [2 104 103].*0.0039;
                app.q19.MaxHeadSize = 1.5;
                app.q19.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 150  && Vangle(i,j) < 165 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q20 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q20.Color = [4 108 84].*0.0039;
                app.q20.MaxHeadSize = 1.5;
                app.q20.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 135  && Vangle(i,j) < 150 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q21 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc ,qavgVv(1:r)*sc ,'AutoScale','off'); 
                app.q21.Color = [10 115 75].*0.0039;
                app.q21.MaxHeadSize = 1.5;
                app.q21.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 120  && Vangle(i,j) < 135 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q22 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q22.Color = [6 124 62].*0.0039;
                app.q22.MaxHeadSize = 1.5;
                app.q22.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if(Vangle(i,j)>= 105  && Vangle(i,j) < 120 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q23 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off'); 
                app.q23.Color = [82 155 31].*0.0039;
                app.q23.MaxHeadSize = 1.5;
                app.q23.MarkerSize = 10;
                end
                
                qx = zeros(1,n);
                qy = zeros(1,n);
                qavgVu = zeros(1,n);
                qavgVv = zeros(1,n);
                r = 0;
                for i = 1:length(Vangle(:,1))
                    for j = 1:length(Vangle(1,:))
                        if( Vangle(i,j)>= 90  && Vangle(i,j) < 105 )
                            r = r+1;
                            qx(r) = (i-1)*jump + 1 ;
                            qy(r) = (j-1)*jump + 1;
                            qavgVu(r) = app.avgVu(i,j);
                            qavgVv(r) = app.avgVv(i,j);           
                        end    
                    end    
                end
                hold(ax,'on');
                if( r ~= 0)
                app.q24 = quiver(ax,qy(1:r),qx(1:r),qavgVu(1:r)*sc,qavgVv(1:r)*sc,'AutoScale','off');
                app.q24.Color = [148 173 19].*0.0039;
                app.q24.MaxHeadSize = 1.5;
                app.q24.MarkerSize = 10;
                end
                
                disp("plotted vectors with 24 different colours");
            end
            if( value == 0)
            %                 delete(app.q1);
            %                 delete(app.q2);
            %                 delete(app.q3);
            %                 delete(app.q4);
            %                 delete(app.q5);
            %                 delete(app.q6);
            %                 delete(app.q7);
            %                 delete(app.q8);
            %                 delete(app.q9);
            %                 delete(app.q10);
            %                 delete(app.q11);
            %                 delete(app.q12);
            %                 delete(app.q13);
            %                 delete(app.q14);
            %                 delete(app.q15);
            %                 delete(app.q16);
            %                 delete(app.q17);
            %                 delete(app.q18);
            %                 delete(app.q19);
            %                 delete(app.q20);
            %                 delete(app.q21);
            %                 delete(app.q22);
            %                 delete(app.q23);
            %                 delete(app.q24);
              quiverObj = [app.q1,app.q2,app.q3,app.q4,app.q5,app.q6,app.q7,app.q8,app.q9,app.q10,app.q11,app.q12,app.q13,app.q14,app.q15,app.q16,app.q17,app.q18,app.q19,app.q20,app.q21,app.q22,app.q23,app.q24];
              delete(quiverObj);  
            end
        end

        % Button pushed function: InputVideoFileButton
        function OpenFileDialog(app, event)
            [app.file,~] = uigetfile('*.avi');
            %             disp(file);
                        videoObject = VideoReader(app.file);
                        ax1 = app.UIAxes;
                        vidFrame = readFrame(videoObject);
                        image(vidFrame, 'Parent',ax1);
                        set(ax1, 'Visible', 'off');
                         ax2 = app.UIAxes2;
                         image(vidFrame, 'Parent',ax2);
                         set(ax2, 'Visible', 'off');
            %                         ax2 = app.UIAxes2;
            %                             while hasFrame(videoObject)
            %                             vidFrame = readFrame(videoObject);
            %                             image(vidFrame, 'Parent',ax2);
            %                             set(ax2, 'Visible', 'off');
            %                             pause(1/(0.1*videoObject.FrameRate));
            %                             disp(videoObject.FrameRate);
            %                             end
        end

        % Value changed function: playpauseButton
        function PlayorPauseVideo(app, event)
             value = app.playpauseButton.Value;
             ax2 = app.UIAxes2;
             videoObject = VideoReader(app.file);
             app.speed = 0.1*app.fast/app.slow;
             
             while (value ==1 )
                    
                    videoFrame = read(videoObject);
                    nframe = videoObject.NumberOfFrames;
                    
                    image(videoFrame(:,:,:,app.frm), 'Parent',ax2);
                    
                    set(ax2, 'Visible', 'off');


                    if(app.VectorsVideoButton.Value == 1)
                                       Preprocess2(app);
                                       CalculateUxy(app);
                                       Computation(app,app.roiPoints,app.preImage,app.U);
                                       ComputationForvecstream(app);
                       PlotVectors2(app);
                    end
                    if(app.StreamlinesVideoButton.Value == 1)
                                       Preprocess2(app);
                                       CalculateUxy(app);
                                       Computation(app,app.roiPoints,app.preImage,app.U);
                                       ComputationForvecstream(app);
                        PlotStreamlines2(app);
                    end
                    if(app.colourvectorsvideoButton.Value == 1)
                        Preprocess2(app);
                                       CalculateUxy(app);
                                       Computation(app,app.roiPoints,app.preImage,app.U);
                                       ComputationForvecstream(app);
                        manyColourvectorsvideo(app);
                    end
                    %%%pause(1/(app.speed*videoObject.FrameRate));
                    value = app.playpauseButton.Value;
                    app.frm = app.frm + 1;
                    if(app.frm == nframe + 1)
                        app.frm = 1;
                    end
                %disp(videoObject.FrameRate);
            %                     if(hasFrame(videoObject)==0)
            %                         videoObject = VideoReader(app.file);
            %                     end  
               % else
                    
             end
             if(value == 0)
                   value = app.playpauseButton.Value;
             end
             
            
        end

        % Value changed function: DensitySlider
        function PointDensity(app, event)
            value = app.DensitySlider.Value;
            
            ComputationForvecstream(app);
            if(app.VectorsButton.Value==1)
                delete(app.q);
                PlotVectors(app);
            end
            if(app.StreamlinesButton.Value == 1)
                delete(app.hand);
                PlotStreamlines(app);
            end
        
        end

        % Value changed function: ColourSlider
        function SingleColourSlider(app, event)
            value = app.ColourSlider.Value;
           % delete(app.q);
            n = value;
            r = 1.0;
            g = 1.0;
            b = 1.0;
            if (n<=100)
                r = 1.0;
                g = n/100.0;
                b = 0.0;
            else 
                if (n<=200)
                    r = (200.0 - n)/100.0;
                    g = 1;
                    b = 0.0;
                else 
                    if (n<=300)
                        r = 0.0;
                        g = 1.0;
                        b = (n - 200.0)/100.0;
                    else
                        if (n<=400)
                            r = 0.0;
                            g = (400.0-n)/100.0;
                            b = 1.0;
                        else 
                            r = (n - 400.0)/100.0;
                            g = 0.0;
                            b = 1.0;
                        end
                    end
                end
            end
            app.r = r;
            app.g = g;
            app.b = b;
            
            if(app.VectorsButton.Value == 1)
             delete(app.q);
             PlotVectors(app);
            %app.q.Color = [app.r app.g app.b];
            end
            if(app.StreamlinesButton.Value == 1)
                delete(app.hand);
                PlotStreamlines(app);
            end
        
        end

        % Button pushed function: SlowButton
        function Slowvideo(app, event)
            app.slow = app.slow + 1;
        end

        % Value changed function: StreamlinesButton
        function StreamlinesDisplay(app, event)
            value = app.StreamlinesButton.Value;
            if(value == 1)
                PlotStreamlines(app);
            end
            if(value == 0)
                delete(app.hand); 
            end
        end

        % Value changed function: StreamlinesVideoButton
        function StreamlinesDisplay2(app, event)
            value = app.StreamlinesVideoButton.Value;
            if(value == 0)
                delete(app.handvid);
            end
        end

        % Value changed function: VectorsButton
        function VectorsDisplay(app, event)
            value = app.VectorsButton.Value;
            if(value == 1)
            
            
            PlotVectors(app);
            end
            if(value == 0)
               delete(app.q); 
            end
        end

        % Value changed function: VectorsVideoButton
        function VectorsDisplay2(app, event)
            value = app.VectorsVideoButton.Value;
            if(value == 0)
                delete(app.qvid);
            end
        end

        % Value changed function: LengthSlider
        function VectorsLength(app, event)
            value = app.LengthSlider.Value;
            if(app.VectorsButton.Value == 1)
                delete(app.q);
                PlotVectors(app);
            end
            if(app.ColourVectorsButton.Value == 1)
              quiverObj = [app.q1,app.q2,app.q3,app.q4,app.q5,app.q6,app.q7,app.q8,app.q9,app.q10,app.q11,app.q12,app.q13,app.q14,app.q15,app.q16,app.q17,app.q18,app.q19,app.q20,app.q21,app.q22,app.q23,app.q24];
              delete(quiverObj);
              ManyColourVectors(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputVideoFileButton = uibutton(app.UIFigure, 'push');
            app.InputVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileDialog, true);
            app.InputVideoFileButton.Text = 'Input Video File';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity :';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @GetVelocityColorbar, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Video')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.DensitySliderLabel = uilabel(app.UIFigure);
            app.DensitySliderLabel.Text = 'Density';
            app.DensitySlider = uislider(app.UIFigure);
            app.DensitySlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.DensitySlider.ValueChangedFcn = createCallbackFcn(app, @PointDensity, true);
            app.VectorsButton = uibutton(app.UIFigure, 'state');
            app.VectorsButton.ValueChangedFcn = createCallbackFcn(app, @VectorsDisplay, true);
            app.VectorsButton.Text = 'Vectors';
            app.StreamlinesButton = uibutton(app.UIFigure, 'state');
            app.StreamlinesButton.ValueChangedFcn = createCallbackFcn(app, @StreamlinesDisplay, true);
            app.StreamlinesButton.Text = 'Streamlines';
            app.LengthSlider = uislider(app.UIFigure);
            app.LengthSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.LengthSlider.ValueChangedFcn = createCallbackFcn(app, @VectorsLength, true);
            app.LengthSliderLabel = uilabel(app.UIFigure);
            app.LengthSliderLabel.Text = 'Length';
            app.ColourSliderLabel = uilabel(app.UIFigure);
            app.ColourSliderLabel.Text = 'Colour';
            app.ColourSlider = uislider(app.UIFigure);
            app.ColourSlider.Limits = [0 500];
            app.ColourSlider.MajorTickLabels = {'0', '50', '100', '150', '200', '250', '300', '350', '400', '450', '500'};
            app.ColourSlider.ValueChangedFcn = createCallbackFcn(app, @SingleColourSlider, true);
            app.ChooseFrameSliderLabel = uilabel(app.UIFigure);
            app.ChooseFrameSliderLabel.Text = 'Choose Frame';
            app.ChooseFrameSlider = uislider(app.UIFigure);
            app.ChooseFrameSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.ChooseFrameSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseFrame, true);
            app.ColourVectorsButton = uibutton(app.UIFigure, 'state');
            app.ColourVectorsButton.ValueChangedFcn = createCallbackFcn(app, @ManyColourVectors, true);
            app.ColourVectorsButton.Text = '24 Colour Vectors';
            app.BoundaryButton = uibutton(app.UIFigure, 'push');
            app.BoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @DrawBoundary, true);
            app.BoundaryButton.Text = 'Boundary';
            app.CentreButton = uibutton(app.UIFigure, 'push');
            app.CentreButton.ButtonPushedFcn = createCallbackFcn(app, @GetCentre, true);
            app.CentreButton.Text = 'Centre';
            app.CropColorBarButton = uibutton(app.UIFigure, 'push');
            app.CropColorBarButton.ButtonPushedFcn = createCallbackFcn(app, @GetColorbar, true);
            app.CropColorBarButton.Text = 'Crop ColorBar';
            app.SlowButton = uibutton(app.UIFigure, 'push');
            app.SlowButton.ButtonPushedFcn = createCallbackFcn(app, @Slowvideo, true);
            app.SlowButton.Text = 'Slow';
            app.FastButton = uibutton(app.UIFigure, 'push');
            app.FastButton.ButtonPushedFcn = createCallbackFcn(app, @Fastvideo, true);
            app.FastButton.Text = 'Fast';
            app.playpauseButton = uibutton(app.UIFigure, 'state');
            app.playpauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayorPauseVideo, true);
            app.playpauseButton.Text = 'play/pause';
            app.VectorsVideoButton = uibutton(app.UIFigure, 'state');
            app.VectorsVideoButton.ValueChangedFcn = createCallbackFcn(app, @VectorsDisplay2, true);
            app.VectorsVideoButton.Text = 'Vectors Video';
            app.StreamlinesVideoButton = uibutton(app.UIFigure, 'state');
            app.StreamlinesVideoButton.ValueChangedFcn = createCallbackFcn(app, @StreamlinesDisplay2, true);
            app.StreamlinesVideoButton.Text = 'Streamlines Video';
            app.ComputeButton = uibutton(app.UIFigure, 'state');
            app.ComputeButton.ValueChangedFcn = createCallbackFcn(app, @ComputingFunction, true);
            app.ComputeButton.Text = 'Compute';
            app.colourvectorsvideoButton = uibutton(app.UIFigure, 'state');
            app.colourvectorsvideoButton.ValueChangedFcn = createCallbackFcn(app, @ColourVectorsVideo, true);
            app.colourvectorsvideoButton.Text = '24 colour vectors video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vfmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end